classdef DSP_finale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MixerTab                      matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        SaveOutputButton              matlab.ui.control.Button
        PlayMixedAudioButton          matlab.ui.control.Button
        StopAudioButton               matlab.ui.control.Button
        UploadAudio1Button            matlab.ui.control.Button
        MixAudioButton                matlab.ui.control.Button
        UploadAudio2Button            matlab.ui.control.Button
        AUDIOMIXERLabel               matlab.ui.control.Label
        AudioProcessingApplicationLabel  matlab.ui.control.Label
        PlayAudio2Button              matlab.ui.control.Button
        PlayAudio1Button              matlab.ui.control.Button
        AUDIO2Label                   matlab.ui.control.Label
        AUDIO1Label                   matlab.ui.control.Label
        graph2                        matlab.ui.control.UIAxes
        graph3                        matlab.ui.control.UIAxes
        graph1                        matlab.ui.control.UIAxes
        SeparatorTab                  matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        InputNoofIterationsEditField  matlab.ui.control.NumericEditField
        InputNoofIterationsEditFieldLabel  matlab.ui.control.Label
        PlayAudioButton               matlab.ui.control.Button
        StopAudioButton_2             matlab.ui.control.Button
        SeparateAudioButton           matlab.ui.control.Button
        PlaySource2Button             matlab.ui.control.Button
        PlaySource1Button             matlab.ui.control.Button
        UploadAudioButton             matlab.ui.control.Button
        AUDIOSEPARATORLabel           matlab.ui.control.Label
        AudioProcessingApplicationLabel_2  matlab.ui.control.Label
        graph4                        matlab.ui.control.UIAxes
        graph6                        matlab.ui.control.UIAxes
        graph5                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        Property2 % Description
        sig1 % signal 1
        sig2 % signal 2
        sig1_resampled
        sig2_resampled
        sig_resample
        fs1 % frequency sample for signal 1
        fs2 % frequency sample for signal 2
        AudioPlayer % Store audioplayer object
        fsResample = 16000; % resampling frequency [Hz]
        minLength
        mix
        sep
        cost
        source1
        source2
        source1Fs
        source2Fs
        % Parameter
        seed = 1; % pseudo random seed
        ns = 2; % number of sources
        fftSize = 4096; % window length in STFT [points]
        shiftSize = 2048; % shift length in STFT [points]
        nb = 20; % number of NMF bases for all sources (total bases)
        it % number of iterations (define by checking convergence behavior with drawConv=true)
        drawConv = true; % true or false (true: plot cost function values in each iteration and show convergence behavior, false: faster and do not plot cost function values)
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadAudio1Button
        function uploadAudio1(app, event)
            % Open a file selection dialog for .wav files
            [fileName, filePath] = uigetfile('*.wav', 'Select an Audio File');
            
            % Check if the user cancels the dialog
            if isequal(fileName, 0)
                disp('User canceled file upload.');
                return;
            end
            
            % Full file path of the selected file
            fullFilePath = fullfile(filePath, fileName);
            
            % Load the audio file
            try
                [app.sig1, app.fs1] = audioread(fullFilePath);
                disp(['File "' fileName '" successfully loaded.']);
                
                % Update a UI element (e.g., a label) to confirm upload
                app.AUDIO1Label.Text = ['Uploaded: ' fileName];

                % Update Play button Enable
                app.PlayAudio1Button.Enable = "on";

                % Plot the audio signal on the UIAxes
                time = (0:length(app.sig1) - 1) / app.fs1; % Time vector
                plot(app.graph1, time, app.sig1); % Plot signal on the UIAxes
                title(app.graph1, 'Audio Signal 1'); % Set title
                xlabel(app.graph1, 'Time (s)');    % Set x-axis label
                ylabel(app.graph1, 'Amplitude');  % Set y-axis label
            catch ME
                % Handle errors, such as file format issues
                disp(['Error loading file: ' ME.message]);
                uialert(app.UIFigure, 'Error loading file. Please select a valid .wav file.', 'File Error');
            end
        end

        % Button pushed function: PlayAudio1Button
        function playAudio1(app, event)
             % Check if audio data exists
            if isempty(app.sig1) || isempty(app.fs1)
                uialert(app.UIFigure, 'No audio file uploaded. Please upload a file first.', 'Playback Error');
                return;
            end
            
            % Create an audioplayer object and play the audio
            try
                app.AudioPlayer = audioplayer(app.sig1, app.fs1);
                disp('Playing audio1...');
                playblocking(app.AudioPlayer);
            catch ME
                % Handle playback errors
                disp(['Error playing audio: ' ME.message]);
                uialert(app.UIFigure, 'Error playing audio. Please try again.', 'Playback Error');
            end
        end

        % Button pushed function: StopAudioButton, StopAudioButton_2
        function stopAudio(app, event)
             % Check if AudioPlayer exists and is playing
            if isempty(app.AudioPlayer)
                uialert(app.UIFigure, 'No audio is currently playing.', 'Stop Error');
                return;
            end
            
            % Stop the audio playback
            try
                stop(app.AudioPlayer);
                disp('Audio stopped.');
            catch ME
                disp(['Error stopping audio: ' ME.message]);
                uialert(app.UIFigure, 'Error stopping audio. Please try again.', 'Stop Error');
            end
        end

        % Button pushed function: UploadAudio2Button
        function uploadAudio2(app, event)
            % Open a file selection dialog for .wav files
            [fileName, filePath] = uigetfile('*.wav', 'Select an Audio File');
            
            % Check if the user cancels the dialog
            if isequal(fileName, 0)
                disp('User canceled file upload.');
                return;
            end
            
            % Full file path of the selected file
            fullFilePath = fullfile(filePath, fileName);
            
            % Load the audio file
            try
                [app.sig2, app.fs2] = audioread(fullFilePath);
                disp(['File "' fileName '" successfully loaded.']);
                
                % Update a UI element (e.g., a label) to confirm upload
                app.AUDIO2Label.Text = ['Uploaded: ' fileName];

                % Update Play button Enable
                app.PlayAudio2Button.Enable = "on";
                app.MixAudioButton.Enable = "on";

                % Plot the audio signal on the UIAxes
                time = (0:length(app.sig2) - 1) / app.fs2; % Time vector
                plot(app.graph2, time, app.sig2); % Plot signal on the UIAxes
                title(app.graph2, 'Audio Signal 2'); % Set title
                xlabel(app.graph2, 'Time (s)');    % Set x-axis label
                ylabel(app.graph2, 'Amplitude');  % Set y-axis label
            catch ME
                % Handle errors, such as file format issues
                disp(['Error loading file: ' ME.message]);
                uialert(app.UIFigure, 'Error loading file. Please select a valid .wav file.', 'File Error');
            end
        end

        % Button pushed function: PlayAudio2Button
        function playAudio2(app, event)
             % Check if audio data exists
            if isempty(app.sig2) || isempty(app.fs2)
                uialert(app.UIFigure, 'No audio file uploaded. Please upload a file first.', 'Playback Error');
                return;
            end
            
            % Create an audioplayer object and play the audio
            try
                app.AudioPlayer = audioplayer(app.sig2, app.fs2);
                disp('Playing audio2...');
                playblocking(app.AudioPlayer);
            catch ME
                % Handle playback errors
                disp(['Error playing audio: ' ME.message]);
                uialert(app.UIFigure, 'Error playing audio. Please try again.', 'Playback Error');
            end
        end

        % Button pushed function: MixAudioButton
        function mixAudio(app, event)
            % Resample both signals to the same frequency
            app.sig1_resampled = resample(app.sig1, app.fsResample, app.fs1, 100);
            app.sig2_resampled = resample(app.sig2, app.fsResample, app.fs2, 100);
            
            % Match the lengths by truncating the longer signal
            app.minLength = min(size(app.sig1_resampled, 1), size(app.sig2_resampled, 1));
            app.sig_resample(:,:,1) = app.sig1_resampled(1:app.minLength, :);
            app.sig_resample(:,:,2) = app.sig2_resampled(1:app.minLength, :);

            % Mixing source images in each channel to produce observed signal
            app.mix(:,1) = app.sig_resample(:,1,1) + app.sig_resample(:,1,2);
            app.mix(:,2) = app.sig_resample(:,2,1) + app.sig_resample(:,2,2);

           disp('Successfully mixed two sources!');

           app.PlayMixedAudioButton.Enable = "on";
           app.SaveOutputButton.Enable = "on";
           time = (0:length(app.mix) - 1) / app.fsResample; % Time vector
           plot(app.graph3, time, app.mix); % Plot signal on the UIAxes
           title(app.graph3, 'Mixed Audio Signal'); % Set title
           xlabel(app.graph3, 'Time (s)');    % Set x-axis label
           ylabel(app.graph3, 'Amplitude');  % Set y-axis label
        end

        % Button pushed function: PlayAudioButton, PlayMixedAudioButton
        function playMixedAudio(app, event)
             % Check if audio data exists
            if isempty(app.mix) || isempty(app.fsResample)
                uialert(app.UIFigure, 'No Mixed Audio filr. Please mix two sources first.', 'Playback Error');
                return;
            end
            
            % Create an audioplayer object and play the audio
            try
                app.AudioPlayer = audioplayer(app.mix, app.fsResample);
                disp('Playing Mixed Audio...');
                playblocking(app.AudioPlayer);
            catch ME
                % Handle playback errors
                disp(['Error playing audio: ' ME.message]);
                uialert(app.UIFigure, 'Error playing audio. Please try again.', 'Playback Error');
            end
        end

        % Button pushed function: SaveOutputButton
        function saveAudio(app, event)
            % Ask user to specify a location and filename to save the mixed audio
            [fileName, filePath] = uiputfile('*.wav', 'Save Mixed Audio');
            
            % Check if user canceled the save dialog
            if isequal(fileName, 0)
                disp('User canceled the save action.');
                return;
            end
            
            % Full file path for saving the mixed audio
            fullFilePath = fullfile(filePath, fileName);
            
            % Save the mixed audio to the specified file
            try
                audiowrite(fullFilePath, app.mix, app.fsResample);
                disp(['Mixed audio saved to: ' fullFilePath]);
            catch ME
                disp(['Error saving mixed audio: ' ME.message]);
                uialert(app.UIFigure, 'Error saving audio. Please try again.', 'Save Error');
            end
        end

        % Button pushed function: UploadAudioButton
        function uploadMix(app, event)
             % Open file selection dialog to choose a .wav file
            [fileName, filePath] = uigetfile('*.wav', 'Select Mixed Audio File');
            
            % Check if the user selected a file
            if isequal(fileName, 0)
                disp('User canceled the file upload.');
                return; % Exit if no file was selected
            end
            
            % Full file path of the selected audio file
            fullFilePath = fullfile(filePath, fileName);
            
            try
                % Read the audio file
                [audioData, sampleRate] = audioread(fullFilePath);
                
                % Check if the sample rate is different from fsResample
                if sampleRate ~= app.fsResample
                    % Resample the audio to match fsResample
                    audioData = resample(audioData, app.fsResample, sampleRate);
                    disp(['Sample rate of the uploaded audio was ', num2str(sampleRate), '. Resampling to ', num2str(app.fsResample), '.']);
                else
                    disp('Sample rate of the uploaded audio matches fsResample. No resampling needed.');
                end
               
               % Process the Audio 
               app.mix = processAudio(audioData, app.fsResample);
                
                % Optionally update the UI (e.g., enable play button)
                app.PlayAudioButton.Enable = 'on'; % Enable Play button after upload
                app.SeparateAudioButton.Enable = "on";
                
                % Update the UI or perform further actions as needed
                % e.g., plot the waveform in UIAxes if needed
                plot(app.graph4, (1:length(app.mix)) / app.fsResample, app.mix);
                title(app.graph4, 'Waveform of Uploaded Mixed Audio');
                xlabel(app.graph4, 'Time (s)');
                ylabel(app.graph4, 'Amplitude');
                
            catch ME
                disp(['Error uploading audio file: ' ME.message]);
                uialert(app.UIFigure, 'Failed to upload the audio file. Please try again.', 'Upload Error');
            end
        end

        % Button pushed function: SeparateAudioButton
        function separateAudio(app, event)
            try
                % Fix random seed for reproducibility
                RandStream.setGlobalStream(RandStream('mt19937ar', 'Seed', app.seed));
               
                % get number of iterations
                getIteration(app,event);

                % Blind source separation using multichannel NMF
                [app.sep, app.cost] = bss_multichannelNMF(app.mix, app.ns, app.nb, app.fftSize, app.shiftSize, app.it, app.drawConv);
                
                % Write the separated signals to files
                audiowrite("estimatedSignal1.wav", app.sep(:, :, 1), app.fsResample); % Estimated signal 1
                audiowrite("estimatedSignal2.wav", app.sep(:, :, 2), app.fsResample); % Estimated signal 2
                
                % Log success message and display evaluation results
                disp('Audio sources separated successfully');
      
                % Read the separated audio files and store them in the app
                [app.source1, app.source1Fs] = audioread('estimatedSignal1.wav');
                [app.source2, app.source2Fs] = audioread('estimatedSignal2.wav');
                
                % Ensure the audio data is valid
                if isempty(app.source1) || isempty(app.source2)
                    error('The separated audio files contain no data. Please check the separation process.');
                end
                
                % Enable the Play buttons for the separated audio
                app.PlaySource1Button.Enable = "on";
                app.PlaySource2Button.Enable = "on";


                % Optionally, update the UI to plot the separated signals
                % Plot source 1
                plot(app.graph5, (1:length(app.sep(:, :, 1))) / app.source1Fs, app.sep(:, :, 1));
                title(app.graph5, 'Estimated Signal 1');
                xlabel(app.graph5, 'Time (s)');
                ylabel(app.graph5, 'Amplitude');
                
                % Plot source 2
                plot(app.graph6, (1:length(app.sep(:, :, 2))) / app.source2Fs, app.sep(:, :, 2));
                title(app.graph6, 'Estimated Signal 2');
                xlabel(app.graph6, 'Time (s)');
                ylabel(app.graph6, 'Amplitude');
                
            catch ME
                % Log and display error message
                disp(['Error during audio separation: ', ME.message]);
                uialert(app.UIFigure, 'Audio separation failed. Please check the input and parameters.', 'Separation Error');
            end
        end

        % Button pushed function: PlaySource1Button
        function playSource1(app, event)
            % Check if audio data exists
            if isempty(app.source1)
                uialert(app.UIFigure, 'No audio file uploaded. Please upload a file first.', 'Playback Error');
                return;
            end
            
            % Create an audioplayer object and play the audio
            try
                app.AudioPlayer = audioplayer(app.source1, app.source1Fs);
                disp('Playing source1...');
                playblocking(app.AudioPlayer);
            catch ME
                % Handle playback errors
                disp(['Error playing audio: ' ME.message]);
                uialert(app.UIFigure, 'Error playing audio. Please try again.', 'Playback Error');
            end
        end

        % Button pushed function: PlaySource2Button
        function playSource2(app, event)

            % Create an audioplayer object and play the audio
            try
                app.AudioPlayer = audioplayer(app.source2, app.source2Fs);
                disp('Playing source2...');
                playblocking(app.AudioPlayer);
            catch ME
                % Handle playback errors
                disp(['Error playing audio: ' ME.message]);
                uialert(app.UIFigure, 'Error playing audio. Please try again.', 'Playback Error');
            end
        end

        % Value changed function: InputNoofIterationsEditField
        function getIteration(app, event)
            value = app.InputNoofIterationsEditField.Value;
            app.it = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MixerTab = uitab(app.TabGroup);
            app.MixerTab.Title = 'Mixer';
            app.GridLayout = uigridlayout(app.MixerTab);
            app.graph1 = uiaxes(app.GridLayout);
            title(app.graph1, 'Title')
            xlabel(app.graph1, 'X')
            ylabel(app.graph1, 'Y')
            zlabel(app.graph1, 'Z')
            app.graph3 = uiaxes(app.GridLayout);
            title(app.graph3, 'Title')
            xlabel(app.graph3, 'X')
            ylabel(app.graph3, 'Y')
            zlabel(app.graph3, 'Z')
            app.graph2 = uiaxes(app.GridLayout);
            title(app.graph2, 'Title')
            xlabel(app.graph2, 'X')
            ylabel(app.graph2, 'Y')
            zlabel(app.graph2, 'Z')
            app.AUDIO1Label = uilabel(app.GridLayout);
            app.AUDIO1Label.Text = 'AUDIO 1';
            app.AUDIO2Label = uilabel(app.GridLayout);
            app.AUDIO2Label.Text = 'AUDIO 2';
            app.PlayAudio1Button = uibutton(app.GridLayout, 'push');
            app.PlayAudio1Button.ButtonPushedFcn = createCallbackFcn(app, @playAudio1, true);
            app.PlayAudio1Button.Enable = 'off';
            app.PlayAudio1Button.Text = 'Play Audio 1';
            app.PlayAudio2Button = uibutton(app.GridLayout, 'push');
            app.PlayAudio2Button.ButtonPushedFcn = createCallbackFcn(app, @playAudio2, true);
            app.PlayAudio2Button.Enable = 'off';
            app.PlayAudio2Button.Text = 'Play Audio 2';
            app.AudioProcessingApplicationLabel = uilabel(app.GridLayout);
            app.AudioProcessingApplicationLabel.Text = 'Audio Processing Application';
            app.AUDIOMIXERLabel = uilabel(app.GridLayout);
            app.AUDIOMIXERLabel.Text = 'AUDIO MIXER';
            app.UploadAudio2Button = uibutton(app.GridLayout, 'push');
            app.UploadAudio2Button.ButtonPushedFcn = createCallbackFcn(app, @uploadAudio2, true);
            app.UploadAudio2Button.Text = 'Upload Audio 2';
            app.MixAudioButton = uibutton(app.GridLayout, 'push');
            app.MixAudioButton.ButtonPushedFcn = createCallbackFcn(app, @mixAudio, true);
            app.MixAudioButton.Enable = 'off';
            app.MixAudioButton.Text = 'Mix Audio';
            app.UploadAudio1Button = uibutton(app.GridLayout, 'push');
            app.UploadAudio1Button.ButtonPushedFcn = createCallbackFcn(app, @uploadAudio1, true);
            app.UploadAudio1Button.Text = 'Upload Audio 1';
            app.StopAudioButton = uibutton(app.GridLayout, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @stopAudio, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.PlayMixedAudioButton = uibutton(app.GridLayout, 'push');
            app.PlayMixedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @playMixedAudio, true);
            app.PlayMixedAudioButton.Enable = 'off';
            app.PlayMixedAudioButton.Text = 'Play Mixed Audio';
            app.SaveOutputButton = uibutton(app.GridLayout, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @saveAudio, true);
            app.SaveOutputButton.Enable = 'off';
            app.SaveOutputButton.Text = 'Save Output';
            app.SeparatorTab = uitab(app.TabGroup);
            app.SeparatorTab.Title = 'Separator';
            app.GridLayout2 = uigridlayout(app.SeparatorTab);
            app.graph5 = uiaxes(app.GridLayout2);
            title(app.graph5, 'Title')
            xlabel(app.graph5, 'X')
            ylabel(app.graph5, 'Y')
            zlabel(app.graph5, 'Z')
            app.graph6 = uiaxes(app.GridLayout2);
            title(app.graph6, 'Title')
            xlabel(app.graph6, 'X')
            ylabel(app.graph6, 'Y')
            zlabel(app.graph6, 'Z')
            app.graph4 = uiaxes(app.GridLayout2);
            title(app.graph4, 'Title')
            xlabel(app.graph4, 'X')
            ylabel(app.graph4, 'Y')
            zlabel(app.graph4, 'Z')
            app.AudioProcessingApplicationLabel_2 = uilabel(app.GridLayout2);
            app.AudioProcessingApplicationLabel_2.Text = 'Audio Processing Application';
            app.AUDIOSEPARATORLabel = uilabel(app.GridLayout2);
            app.AUDIOSEPARATORLabel.Text = 'AUDIO SEPARATOR';
            app.UploadAudioButton = uibutton(app.GridLayout2, 'push');
            app.UploadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @uploadMix, true);
            app.UploadAudioButton.Text = 'Upload Audio';
            app.PlaySource1Button = uibutton(app.GridLayout2, 'push');
            app.PlaySource1Button.ButtonPushedFcn = createCallbackFcn(app, @playSource1, true);
            app.PlaySource1Button.Enable = 'off';
            app.PlaySource1Button.Text = 'Play Source 1';
            app.PlaySource2Button = uibutton(app.GridLayout2, 'push');
            app.PlaySource2Button.ButtonPushedFcn = createCallbackFcn(app, @playSource2, true);
            app.PlaySource2Button.Enable = 'off';
            app.PlaySource2Button.Text = 'Play Source 2';
            app.SeparateAudioButton = uibutton(app.GridLayout2, 'push');
            app.SeparateAudioButton.ButtonPushedFcn = createCallbackFcn(app, @separateAudio, true);
            app.SeparateAudioButton.Enable = 'off';
            app.SeparateAudioButton.Text = 'Separate Audio';
            app.StopAudioButton_2 = uibutton(app.GridLayout2, 'push');
            app.StopAudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @stopAudio, true);
            app.StopAudioButton_2.Text = 'Stop Audio';
            app.PlayAudioButton = uibutton(app.GridLayout2, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @playMixedAudio, true);
            app.PlayAudioButton.Enable = 'off';
            app.PlayAudioButton.Text = 'Play Audio ';
            app.InputNoofIterationsEditFieldLabel = uilabel(app.GridLayout2);
            app.InputNoofIterationsEditFieldLabel.Text = 'Input No. of Iterations';
            app.InputNoofIterationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InputNoofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @getIteration, true);
            app.InputNoofIterationsEditField.Value = 300;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_finale

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end